classdef fermentationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FermentationSimulationUIFigure  matlab.ui.Figure
        YeastOzLabel                    matlab.ui.control.Label
        yeastGauge                      matlab.ui.control.LinearGauge
        CO2LbsLabel                     matlab.ui.control.Label
        co2Gauge                        matlab.ui.control.LinearGauge
        EthanolGalLabel                 matlab.ui.control.Label
        ethanolGauge                    matlab.ui.control.LinearGauge
        GlucoseLbsLabel                 matlab.ui.control.Label
        glucoseGauge                    matlab.ui.control.LinearGauge
        runAnimationButton              matlab.ui.control.Button
        closeButton                     matlab.ui.control.Button
        yeastAxes                       matlab.ui.control.UIAxes
        glucoseAxes                     matlab.ui.control.UIAxes
        ethanolAxes                     matlab.ui.control.UIAxes
        co2Axes                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        yeast   % Yeast vector to pass around
        glucose % Glucose vector to pass around
        ethanol % Ethanol vector to pass around
        co2     % CO2 vector to pass around
        time    % Time vector to pass around
        running = true% Bool to check if running
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, yeastVec, glucoseVec, ethanolVec, co2Vec, timeVec)
            
            %Change the gauge limits to the max values
            app.yeastGauge.Limits   = [0, yeastVec(end)];
            app.glucoseGauge.Limits = [0, glucoseVec(1)];
            app.ethanolGauge.Limits = [0, ethanolVec(end)];
            app.co2Gauge.Limits     = [0, co2Vec(end)];
            
            %Sets Axis time limits
            app.yeastAxes.XLim   = [0, timeVec(end)];
            app.glucoseAxes.XLim = [0, timeVec(end)];
            app.ethanolAxes.XLim = [0, timeVec(end)];
            app.co2Axes.XLim     = [0, timeVec(end)];
            
            %Sets Axis value limits
            app.yeastAxes.YLim   = [0, yeastVec(end)];
            app.glucoseAxes.YLim = [0, glucoseVec(1)];
            app.ethanolAxes.YLim = [0, ethanolVec(end)];
            app.co2Axes.YLim     = [0, co2Vec(end)];
            
            app.yeast   = yeastVec;
            app.glucose = glucoseVec;
            app.ethanol = ethanolVec;
            app.co2     = co2Vec;
            app.time    = timeVec;
        end

        % Button pushed function: runAnimationButton
        function runAnimationButtonPushed(app, event)
            
            %Creates and animated lines for the animation
            yeastLine   = animatedline(app.yeastAxes, app.time(1), app.yeast(1));
            glucoseLine = animatedline(app.glucoseAxes, app.time(1), app.glucose(1));
            ethanolLine = animatedline(app.ethanolAxes, app.time(1), app.ethanol(1));
            co2Line     = animatedline(app.co2Axes, app.time(1), app.co2(1));
            
            
            for i=1:10000:length(app.time)
                %Animates the line
                addpoints(yeastLine, app.time(i), app.yeast(i))
                addpoints(glucoseLine, app.time(i), app.glucose(i))
                addpoints(ethanolLine, app.time(i), app.ethanol(i))
                addpoints(co2Line, app.time(i), app.co2(i))
                
                %Animates the gauges
                app.yeastGauge.Value = app.yeast(i);
                app.glucoseGauge.Value = app.glucose(i);
                app.ethanolGauge.Value = app.ethanol(i);
                app.co2Gauge.Value = app.co2(i);
                
                drawnow
            end
        end

        % Button pushed function: closeButton
        function closeButtonPushed(app, event)
            app.running = false; %Basically useless
        end

        % Close request function: FermentationSimulationUIFigure
        function FermentationSimulationUIFigureCloseRequest(app, event)
            app.delete()
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FermentationSimulationUIFigure = uifigure;
            app.FermentationSimulationUIFigure.Name = 'Fermentation Simulation';
            app.FermentationSimulationUIFigure.CloseRequestFcn = createCallbackFcn(app, @FermentationSimulationUIFigureCloseRequest, true);
            app.YeastOzLabel = uilabel(app.FermentationSimulationUIFigure);
            app.YeastOzLabel.Text = 'Yeast (Oz.)';
            app.yeastGauge = uigauge(app.FermentationSimulationUIFigure, 'linear');
            app.CO2LbsLabel = uilabel(app.FermentationSimulationUIFigure);
            app.CO2LbsLabel.Text = 'CO2 (Lbs)';
            app.co2Gauge = uigauge(app.FermentationSimulationUIFigure, 'linear');
            app.EthanolGalLabel = uilabel(app.FermentationSimulationUIFigure);
            app.EthanolGalLabel.Text = 'Ethanol (Gal)';
            app.ethanolGauge = uigauge(app.FermentationSimulationUIFigure, 'linear');
            app.GlucoseLbsLabel = uilabel(app.FermentationSimulationUIFigure);
            app.GlucoseLbsLabel.Text = 'Glucose (Lbs)';
            app.glucoseGauge = uigauge(app.FermentationSimulationUIFigure, 'linear');
            app.runAnimationButton = uibutton(app.FermentationSimulationUIFigure, 'push');
            app.runAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @runAnimationButtonPushed, true);
            app.runAnimationButton.Text = 'Run Animation';
            app.closeButton = uibutton(app.FermentationSimulationUIFigure, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.closeButton.Text = 'Close';
            app.yeastAxes = uiaxes(app.FermentationSimulationUIFigure);
            title(app.yeastAxes, 'Yeast Fermentation')
            xlabel(app.yeastAxes, 'Time (Hours)')
            ylabel(app.yeastAxes, 'Yeast (Pounds)')
            app.glucoseAxes = uiaxes(app.FermentationSimulationUIFigure);
            title(app.glucoseAxes, 'Glucose Fermentation')
            xlabel(app.glucoseAxes, 'Time (Hours)')
            ylabel(app.glucoseAxes, 'Glucose (Pounds)')
            app.ethanolAxes = uiaxes(app.FermentationSimulationUIFigure);
            title(app.ethanolAxes, 'Ethanol Fermentation')
            xlabel(app.ethanolAxes, 'Time (Hours)')
            ylabel(app.ethanolAxes, 'Ethanol (Pounds)')
            app.co2Axes = uiaxes(app.FermentationSimulationUIFigure);
            title(app.co2Axes, 'CO_2 Fermentation')
            xlabel(app.co2Axes, 'Time (Hours)')
            ylabel(app.co2Axes, 'CO_2 (Pounds)')
        end
    end

    methods (Access = public)

        % Construct app
        function app = fermentationApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FermentationSimulationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FermentationSimulationUIFigure)
        end
    end
end