classdef hopsSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HopsUIFigure              matlab.ui.Figure
        Hops                      matlab.ui.container.TabGroup
        Hops1Tab                  matlab.ui.container.Tab
        AmountOzEditFieldLabel    matlab.ui.control.Label
        hopAmount1                matlab.ui.control.NumericEditField
        HopsUsedListBoxLabel_5    matlab.ui.control.Label
        hopBox1                   matlab.ui.control.ListBox
        Hops2Tab                  matlab.ui.container.Tab
        AmountOzEditFieldLabel_2  matlab.ui.control.Label
        hopAmount2                matlab.ui.control.NumericEditField
        HopsUsedListBoxLabel_6    matlab.ui.control.Label
        hopBox2                   matlab.ui.control.ListBox
        Hops3Tab                  matlab.ui.container.Tab
        AmountOzEditFieldLabel_3  matlab.ui.control.Label
        hopAmount3                matlab.ui.control.NumericEditField
        HopsUsedListBoxLabel_7    matlab.ui.control.Label
        hopBox3                   matlab.ui.control.ListBox
        Hops4Tab                  matlab.ui.container.Tab
        AmountOzEditFieldLabel_4  matlab.ui.control.Label
        hopAmount4                matlab.ui.control.NumericEditField
        HopsUsedListBoxLabel_8    matlab.ui.control.Label
        hopBox4                   matlab.ui.control.ListBox
        CloseButton               matlab.ui.control.Button
        hopsWeightText            matlab.ui.control.Label
        IBUText                   matlab.ui.control.Label
    end


    properties (Access = public)
        running         = true; % bool to check if still running
        IBU             = 0 % IBU of the beer
        weight          = 1 % Total weight of hops in Ounces
        weightedAcid    = 0 % Weighted average of hops acid and weight
        beer                % Gallons of beer
    end

    methods (Access = private)
        
        function IBUCalc(app)
            app.IBU = app.weightedAcid  * .95 * 7489 / app.beer;
        end
        
        function weightedAcidCalc(app)
            hop1Acid = app.hopAmount1.Value * str2double(app.hopBox1.Value);
            hop2Acid = app.hopAmount2.Value * str2double(app.hopBox2.Value);
            hop3Acid = app.hopAmount3.Value * str2double(app.hopBox3.Value);
            hop4Acid = app.hopAmount4.Value * str2double(app.hopBox4.Value);
            
            app.weightedAcid = hop1Acid + hop2Acid + hop3Acid + hop4Acid;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, beerIn)
            app.beer = beerIn;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.running = false;
        end

        % Value changed function: hopAmount1, hopAmount2, hopAmount3, 
        % hopAmount4, hopBox1, hopBox2, hopBox3, hopBox4
        function hopAmount1ValueChanged(app, event)
            %This will be a catch all callback probably
            app.weight = app.hopAmount1.Value + app.hopAmount2.Value + app.hopAmount3.Value + app.hopAmount4.Value;
            
            weightedAcidCalc(app)
            
            IBUCalc(app)
            
            app.hopsWeightText.Text = sprintf('Weight (Oz.): %g', app.weight);
            app.IBUText.Text        = sprintf('IBU: %g', app.IBU);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HopsUIFigure = uifigure;
            app.HopsUIFigure.Name = 'Hops';
            app.Hops = uitabgroup(app.HopsUIFigure);
            app.Hops1Tab = uitab(app.Hops);
            app.Hops1Tab.Title = 'Hops 1';
            app.AmountOzEditFieldLabel = uilabel(app.Hops1Tab);
            app.AmountOzEditFieldLabel.Text = 'Amount (Oz.)';
            app.hopAmount1 = uieditfield(app.Hops1Tab, 'numeric');
            app.hopAmount1.ValueChangedFcn = createCallbackFcn(app, @hopAmount1ValueChanged, true);
            app.hopAmount1.Value = 1;
            app.HopsUsedListBoxLabel_5 = uilabel(app.Hops1Tab);
            app.HopsUsedListBoxLabel_5.Text = 'Hops Used';
            app.hopBox1 = uilistbox(app.Hops1Tab);
            app.hopBox1.Items = {'Cascade', 'Centennial', 'Columbus', 'Galena', 'Tettnang', 'Saaz', 'Willamette'};
            app.hopBox1.ItemsData = {'.075', ' .12', ' .16', ' .14', ' .035', ' .02', ' .05'};
            app.hopBox1.ValueChangedFcn = createCallbackFcn(app, @hopAmount1ValueChanged, true);
            app.hopBox1.Value = '.075';
            app.Hops2Tab = uitab(app.Hops);
            app.Hops2Tab.Title = 'Hops 2';
            app.AmountOzEditFieldLabel_2 = uilabel(app.Hops2Tab);
            app.AmountOzEditFieldLabel_2.Text = 'Amount (Oz.)';
            app.hopAmount2 = uieditfield(app.Hops2Tab, 'numeric');
            app.hopAmount2.ValueChangedFcn = createCallbackFcn(app, @hopAmount1ValueChanged, true);
            app.HopsUsedListBoxLabel_6 = uilabel(app.Hops2Tab);
            app.HopsUsedListBoxLabel_6.Text = 'Hops Used';
            app.hopBox2 = uilistbox(app.Hops2Tab);
            app.hopBox2.Items = {'Cascade', 'Centennial', 'Columbus', 'Galena', 'Tettnang', 'Saaz', 'Willamette'};
            app.hopBox2.ItemsData = {'.075', ' .12', ' .16', ' .14', ' .035', ' .02', ' .05'};
            app.hopBox2.ValueChangedFcn = createCallbackFcn(app, @hopAmount1ValueChanged, true);
            app.hopBox2.Value = '.075';
            app.Hops3Tab = uitab(app.Hops);
            app.Hops3Tab.Title = 'Hops 3';
            app.AmountOzEditFieldLabel_3 = uilabel(app.Hops3Tab);
            app.AmountOzEditFieldLabel_3.Text = 'Amount (Oz.)';
            app.hopAmount3 = uieditfield(app.Hops3Tab, 'numeric');
            app.hopAmount3.ValueChangedFcn = createCallbackFcn(app, @hopAmount1ValueChanged, true);
            app.HopsUsedListBoxLabel_7 = uilabel(app.Hops3Tab);
            app.HopsUsedListBoxLabel_7.Text = 'Hops Used';
            app.hopBox3 = uilistbox(app.Hops3Tab);
            app.hopBox3.Items = {'Cascade', 'Centennial', 'Columbus', 'Galena', 'Tettnang', 'Saaz', 'Willamette'};
            app.hopBox3.ItemsData = {'.075', ' .12', ' .16', ' .14', ' .035', ' .02', ' .05'};
            app.hopBox3.ValueChangedFcn = createCallbackFcn(app, @hopAmount1ValueChanged, true);
            app.hopBox3.Value = '.075';
            app.Hops4Tab = uitab(app.Hops);
            app.Hops4Tab.Title = 'Hops 4';
            app.AmountOzEditFieldLabel_4 = uilabel(app.Hops4Tab);
            app.AmountOzEditFieldLabel_4.Text = 'Amount (Oz.)';
            app.hopAmount4 = uieditfield(app.Hops4Tab, 'numeric');
            app.hopAmount4.ValueChangedFcn = createCallbackFcn(app, @hopAmount1ValueChanged, true);
            app.HopsUsedListBoxLabel_8 = uilabel(app.Hops4Tab);
            app.HopsUsedListBoxLabel_8.Text = 'Hops Used';
            app.hopBox4 = uilistbox(app.Hops4Tab);
            app.hopBox4.Items = {'Cascade', 'Centennial', 'Columbus', 'Galena', 'Tettnang', 'Saaz', 'Willamette'};
            app.hopBox4.ItemsData = {'.075', ' .12', ' .16', ' .14', ' .035', ' .02', ' .05'};
            app.hopBox4.ValueChangedFcn = createCallbackFcn(app, @hopAmount1ValueChanged, true);
            app.hopBox4.Value = '.075';
            app.CloseButton = uibutton(app.HopsUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.hopsWeightText = uilabel(app.HopsUIFigure);
            app.hopsWeightText.Text = 'Weight (Oz.):';
            app.IBUText = uilabel(app.HopsUIFigure);
            app.IBUText.Text = 'IBU:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = hopsSelector(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HopsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HopsUIFigure)
        end
    end
end