classdef outputTables < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InputOutputsUIFigure  matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        Stats                 matlab.ui.container.Tab
        volumeText            matlab.ui.control.Label
        IBUText               matlab.ui.control.Label
        ABVText               matlab.ui.control.Label
        MillingTab            matlab.ui.container.Tab
        mill                  matlab.ui.control.Table
        Mashing               matlab.ui.container.Tab
        mash                  matlab.ui.control.Table
        LauterTab             matlab.ui.container.Tab
        lauter                matlab.ui.control.Table
        BrewkettleTab         matlab.ui.container.Tab
        brew                  matlab.ui.control.Table
        WhirlpoolTab          matlab.ui.container.Tab
        whirl                 matlab.ui.control.Table
        HeatExchangeTab       matlab.ui.container.Tab
        heat                  matlab.ui.control.Table
        FermentationTab       matlab.ui.container.Tab
        ferment               matlab.ui.control.Table
        ColdStorageTab        matlab.ui.container.Tab
        storage               matlab.ui.control.Table
    end


    properties (Access = public)
        IOStruct % Brewery IO Struct
        IBU      % Beer IBU
        ABV      % Beer ABV
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, beerIBU, beerABV)
            load('IOStruct.mat')

            app.IBU      = beerIBU;
            app.ABV      = beerABV;
            
            app.volumeText.Text = sprintf('Total Gallons of beer: %g', breweryInOut.storage.in.beer);
            app.IBUText.Text    = sprintf('IBU of beer: %g', beerIBU);
            app.ABVText.Text    = sprintf('ABV of beer: %g', beerABV);
            
            %adding data by row to the tables
            app.mill.Data = {'Grain (Pounds)', breweryInOut.mill.in.grain, breweryInOut.mill.out.grain};
            
            app.mash.Data = {'Grain (Pounds)', breweryInOut.mash.in.grain, 0;...
                             'Water (Gallons)', breweryInOut.mash.in.water, 0;...
                             'Wort (Gallons)',  0, breweryInOut.mash.out.wort(1)};
                             
            app.lauter.Data = {'Wort (Gallons)', breweryInOut.lauter.in.wort(1), breweryInOut.lauter.out.wort(1); ...
                               'Water (Gallons', breweryInOut.lauter.in.water, breweryInOut.lauter.out.water; ...
                               'Grain (Pounds)', 0, breweryInOut.lauter.out.grain};
                           
            app.brew.Data = {'Wort (Gallons)', breweryInOut.brew.in.wort(1), breweryInOut.brew.out.wort(1); ...
                             'Water (Gallons)', breweryInOut.brew.in.water, 0; ...
                             'Hops (Ounces)', breweryInOut.brew.in.hops, 0};
                         
            app.whirl.Data = {'Wort (Gallons)', breweryInOut.whirl.in.wort(1), breweryInOut.whirl.out.wort; ...
                              'Trub (Pounds)', 0, breweryInOut.whirl.out.trub};
                          
            app.heat.Data = {'Wort (Gallons)', breweryInOut.heat.in.wort, breweryInOut.heat.out.wort};
            
            app.ferment.Data = {'Wort (Gallons)', breweryInOut.ferment.in.wort, 0; ...
                           'Yeast (Pounds)', breweryInOut.ferment.in.yeast, breweryInOut.ferment.out.yeast; ...
                           'Sugar (Pounds)', breweryInOut.ferment.in.glucose, 0; ...
                           'Alcohol (Gallons)', 0, breweryInOut.ferment.out.ethanol; ...
                           'CO_2 (Pounds)', 0, breweryInOut.ferment.out.co2; ...
                           'Beer (Gallons)', 0, breweryInOut.ferment.out.beer};
                       
            app.storage.Data = {'Beer (Gallons)', breweryInOut.storage.in.beer, 0};
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InputOutputsUIFigure = uifigure;
            app.InputOutputsUIFigure.Name = 'Input/Outputs';
            app.TabGroup = uitabgroup(app.InputOutputsUIFigure);
            app.Stats = uitab(app.TabGroup);
            app.Stats.Title = 'Stats';
            app.volumeText = uilabel(app.Stats);
            app.volumeText.Text = 'Total Gallons of beer:';
            app.IBUText = uilabel(app.Stats);
            app.IBUText.Text = 'IBU of beer:';
            app.ABVText = uilabel(app.Stats);
            app.ABVText.Text = 'ABV of beer:';
            app.MillingTab = uitab(app.TabGroup);
            app.MillingTab.Title = 'Milling';
            app.mill = uitable(app.MillingTab);
            app.mill.ColumnName = {'Material'; 'Input'; 'Output'};
            app.mill.RowName = {};
            app.Mashing = uitab(app.TabGroup);
            app.Mashing.Title = 'Mashing';
            app.mash = uitable(app.Mashing);
            app.mash.ColumnName = {'Material'; 'Input'; 'Output'};
            app.mash.RowName = {};
            app.LauterTab = uitab(app.TabGroup);
            app.LauterTab.Title = 'Lauter';
            app.lauter = uitable(app.LauterTab);
            app.lauter.ColumnName = {'Material'; 'Input'; 'Output'};
            app.lauter.RowName = {};
            app.BrewkettleTab = uitab(app.TabGroup);
            app.BrewkettleTab.Title = 'Brewkettle';
            app.brew = uitable(app.BrewkettleTab);
            app.brew.ColumnName = {'Material'; 'Input'; 'Output'};
            app.brew.RowName = {};
            app.WhirlpoolTab = uitab(app.TabGroup);
            app.WhirlpoolTab.Title = 'Whirlpool';
            app.whirl = uitable(app.WhirlpoolTab);
            app.whirl.ColumnName = {'Material'; 'Input'; 'Output'};
            app.whirl.RowName = {};
            app.HeatExchangeTab = uitab(app.TabGroup);
            app.HeatExchangeTab.Title = 'Heat Exchange';
            app.heat = uitable(app.HeatExchangeTab);
            app.heat.ColumnName = {'Material'; 'Input'; 'Output'};
            app.heat.RowName = {};
            app.FermentationTab = uitab(app.TabGroup);
            app.FermentationTab.Title = 'Fermentation';
            app.ferment = uitable(app.FermentationTab);
            app.ferment.ColumnName = {'Material'; 'Input'; 'Output'};
            app.ferment.RowName = {};
            app.ColdStorageTab = uitab(app.TabGroup);
            app.ColdStorageTab.Title = 'Cold Storage';
            app.storage = uitable(app.ColdStorageTab);
            app.storage.ColumnName = {'Material'; 'Input'; 'Output'};
            app.storage.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = outputTables(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InputOutputsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InputOutputsUIFigure)
        end
    end
end